classdef DRAGON < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow               matlab.ui.Figure
        File                     matlab.ui.container.Menu
        NewMenu                  matlab.ui.container.Menu
        ODFMenu                  matlab.ui.container.Menu
        PoleFigureMenu           matlab.ui.container.Menu
        SaveWorkspaceMenu        matlab.ui.container.Menu
        SavePointstoFileMenu     matlab.ui.container.Menu
        Edit                     matlab.ui.container.Menu
        Panel                    matlab.ui.container.Panel
        BaseGrid                 matlab.ui.container.GridLayout
        Button_RemovePoints      matlab.ui.control.Button
        Label_PF_Pole            matlab.ui.control.Label
        Label_ContourLevel       matlab.ui.control.Label
        Label_ContourExtremum    matlab.ui.control.Label
        Label_Projection         matlab.ui.control.Label
        Label_Method             matlab.ui.control.Label
        Label_Lattice            matlab.ui.control.Label
        Label_ODF_Header         matlab.ui.control.Label
        Label_PF_Header          matlab.ui.control.Label
        Label_ContourHeader      matlab.ui.control.Label
        Label_ActiveHeader       matlab.ui.control.Label
        WorkspaceLabel           matlab.ui.control.Label
        Label_Title              matlab.ui.control.Label
        Label_Subtitle           matlab.ui.control.Label
        Button_LoadPoints        matlab.ui.control.Button
        Button_SavePoints        matlab.ui.control.Button
        Button_MovePoints        matlab.ui.control.Button
        StateButton_Neither      matlab.ui.control.StateButton
        StateButton_Maxima       matlab.ui.control.StateButton
        StateButton_Minima       matlab.ui.control.StateButton
        Button_Preview           matlab.ui.control.Button
        Button_SetLatticeType_4  matlab.ui.control.Button
        DropDown_MethodType      matlab.ui.control.DropDown
        DropDown_ProjectionType  matlab.ui.control.DropDown
        Button_TransformImage    matlab.ui.control.Button
        Button_Save_ODF_PF       matlab.ui.control.Button
        Button_Delete_ODF_PF     matlab.ui.control.Button
        Button_New_ODF_PF        matlab.ui.control.Button
        EditField_DefineLevels   matlab.ui.control.EditField
        Button_DefineLevels      matlab.ui.control.Button
        DropDown_LevelsAs        matlab.ui.control.DropDown
        Label_ActivePole         matlab.ui.control.Label
        Label_ActiveExtremum     matlab.ui.control.Label
        Label_ActiveLevel        matlab.ui.control.Label
        ActivePoleValue          matlab.ui.control.Label
        ActiveExtremumValue      matlab.ui.control.Label
        ActiveLevelValue         matlab.ui.control.Label
        DropDown_Pole            matlab.ui.control.DropDown
        DropDown_LatticeType     matlab.ui.control.DropDown
        Tree_Workspace           matlab.ui.container.Tree
        DropDown_Levels          matlab.ui.control.DropDown
        Button_CollectPoints     matlab.ui.control.Button
        Button_FindImage         matlab.ui.control.Button
        EditField_FindImage      matlab.ui.control.EditField
    end


    properties (Access = public)
        CURRENT_EXT; % Description
        CURRENT_LEVEL; % Description

        CONST_COLORS struct; % struct to hold colors for UI;
        DEFAULTS struct; % struct to hold default values for UI;
        STARTUP_COMPLETE logical = false; % Description
        SAVE_STATE struct;
        UI_GROUPS struct;
        FIG struct;
        PF; % Description
        ODF;
        ALPHA;
    end

    methods (Access = private)

        function rgb = getRGB(app, intensity)
            rgb = app.cmap(round(interp1(app.CLIMS, [1 length(app.cmap)], intensity)), :);
        end

        function set_constants(app)
            app.CONST_COLORS = struct();
            app.CONST_COLORS.CoreOrange = '#FA4616';
            app.CONST_COLORS.CoreBlue = '#0021A5';
            app.CONST_COLORS.BottleBrush = '#D32737';
            app.CONST_COLORS.Alachua = '#F2A900';
            app.CONST_COLORS.Gator = '#22884C';
            app.CONST_COLORS.DarkBlue = '#002657';
            app.CONST_COLORS.Perennial = '#6A2A60';
            app.CONST_COLORS.Black = '#000000';
            app.CONST_COLORS.CoolGrey11 = '#343741';
            app.CONST_COLORS.CoolGrey3 = '#C7C9C8';
            app.CONST_COLORS.WarmGrey1 = '#D8D4D7';
            app.CONST_COLORS.White = '#FFFFFF';

            app.FIG = struct();
            app.FIG.Fig = figure('Name', 'DRAGON Interactive Plot Window -- Do Not Close', 'Visible','on');
            app.FIG.Axes = axes(app.FIG.Fig, 'Units', 'normalized', 'OuterPosition', [0 0 1 1]);

            app.DEFAULTS = struct();
            app.DEFAULTS.ButtonFontSize = 24;
            app.DEFAULTS.HeaderFontSize = 30;
            app.DEFAULTS.TitleFontSize = 60;
        end

        function set_ui_groups(app)

            gODF = findall(app.MainWindow, '-regexp', 'Tag', '.*(?:gODF).*'  );
            gPF = findall(app.MainWindow, '-regexp', 'Tag', '.*(?:gPF).*'  );
            gCon = findall(app.MainWindow, '-regexp', 'Tag', '.*(?:gCon).*'  );
            gAct = findall(app.MainWindow, '-regexp', 'Tag', '.*(?:gAct).*'  );

            app.UI_GROUPS = struct();
            app.UI_GROUPS.ODF = gODF;
            app.UI_GROUPS.PF = gPF;
            app.UI_GROUPS.Contour = gCon;
            app.UI_GROUPS.Active = gAct;

        end

        function swap_extrema(app, selected_button)

            app.StateButton_Maxima.BackgroundColor = app.CONST_COLORS.CoolGrey11;
            app.StateButton_Minima.BackgroundColor = app.CONST_COLORS.CoolGrey11;
            app.StateButton_Neither.BackgroundColor = app.CONST_COLORS.CoolGrey11;

            switch selected_button.Text
                case app.StateButton_Minima.Text
                    app.StateButton_Maxima.Value = false;
                    app.StateButton_Neither.Value = false;
                case app.StateButton_Maxima.Text
                    app.StateButton_Minima.Value = false;
                    app.StateButton_Neither.Value = false;
                case app.StateButton_Neither.Text
                    app.StateButton_Maxima.Value = false;
                    app.StateButton_Minima.Value = false;
                otherwise
                    return;
            end

            selected_button.BackgroundColor = app.CONST_COLORS.Perennial;

        end

        function createODFnode(app)
            node = uitreenode(app.Tree_Workspace, 'Text', sprintf('ODF'), 'Tag', 'ODF');
            %s = uistyle("FontWeight","bold");
            %addStyle(app.Tree_Workspace, s, 'node', node);
        end

        function node = createPFnode(app)
            parent = app.Tree_Workspace.Children(1);
            v = app.DropDown_Pole.Value;
            node = uitreenode(parent,'Text', sprintf('%s Pole', v), 'Tag', 'PF');%_New');
            node.NodeData = Miller(v, app.Tree_Workspace.Children(1).Children(1).NodeData);
            set(app.ActivePoleValue, 'Text', node.NodeData.char);
            %s = uistyle("FontWeight","bold");
            %disp(isvalid(app.Tree_Workspace))
            %addStyle(app.Tree_Workspace, s, 'node', node);

            levels = app.getLevelsFromField;
            t = sprintf(' %g', levels);
            levels_text = sprintf('Levels: [%s ]', t);
            app.DropDown_Levels.Items = arrayfun(@(x) num2str(x), levels, 'UniformOutput', false);
            app.CURRENT_LEVEL = str2double(app.DropDown_Levels.Value);
            app.ActiveLevelValue.Text = num2str(app.CURRENT_LEVEL);
            app.createInfoNode(node, levels_text, levels);

        end

        function createInfoNode(app, parent, info_text, info)
            node = uitreenode(parent, 'Text', info_text);
            node.NodeData = info;
            %s = uistyle("FontAngle","italic");
            %addStyle(app.Tree_Workspace, s, 'node', node);
        end


        function createIMnode(app, parent)
            F = findFile_returnFPath;
            app.EditField_FindImage.Value = F.location;
            node = uitreenode(parent, 'Text', sprintf('Image: %s%s', F.name, F.extension), 'Tag', 'IM');
            nd = struct();
            nd.File = F;
            nd.IM = imread(F.location);
            nd.BC = [];
            nd.Tform = [];
            node.NodeData = nd;
            imshow(node.NodeData.IM, 'Parent', app.FIG.Axes, 'Border', 'tight', 'InitialMagnification', 'fit');
            %s = uistyle("FontAngle","italic");
            %addStyle(app.Tree_Workspace, s, 'node', node);

            nd2 = struct();
            nd2.P = Point();
            nd2.h = [];
            app.createInfoNode(parent, 'Points: 0', nd2);
        end


        function type = getSelectedNodeType(app)
            if length(app.Tree_Workspace.SelectedNodes) ~= 1; return; end

            type = app.Tree_Workspace.SelectedNodes.Tag;
            if strcmp(type(3), '_')
                type = type(1:2);
            else
                type = type(1:3);
            end

        end

        function handle = getSelectedNodeParentODF(app)
            handle = ancestor(app.Tree_Workspace.SelectedNodes, 'uitreenode', 'toplevel');
        end

        function levels_array = getLevelsFromField(app)

            str = app.EditField_DefineLevels.Value;

            if strcmp(app.DropDown_LevelsAs.Value, 'list')
                newStr = split(str, ',');
                newStr = strtrim(newStr);
                levels_array = arrayfun(@(x) str2double(x), newStr);
                levels_array = levels_array';
            end

            if strcmp(app.DropDown_LevelsAs.Value, 'linspace')
                newStr = split(str, '+');

                if length(newStr) == 1
                    lnsp = split(str, ':');
                    lnsp = strtrim(lnsp);
                    levels_array = [str2double(lnsp(1)):str2double(lnsp(2)):str2double(lnsp(3))];
                elseif length(newStr) > 1
                    lnsp = newStr(1);
                    list = newStr(2);

                    lnsp = split(lnsp, ':');
                    lnsp = strtrim(lnsp);
                    lnsp = [str2double(lnsp(1)):str2double(lnsp(2)):str2double(lnsp(3))];

                    list = split(list, ',');
                    list = strtrim(list);
                    list = arrayfun(@(x) str2double(x), list);

                    levels_array = horzcat(lnsp, list');
                end

                levels_array = sort(levels_array);

            end
        end

        function proj = getProjection(app)
            str = app.Tree_Workspace.Children(1).Children(2).NodeData;
            switch str
                case 'eangle'
                    proj = eangleProjection;
                case 'earea'
                    proj = eareaProjection;
                case 'edist'
                    proj = edistProjection;
                otherwise
                    error('Not a type of projection that is currently supported.')
            end
        end


        function refresh_app(app)
            selectedNodes = app.Tree_Workspace.SelectedNodes;

            if isempty(app.Tree_Workspace.SelectedNodes)
                % disable all but workspace, new
                app.UI_Enable(app.UI_GROUPS.ODF, 'off');
                app.Button_Delete_ODF_PF.Enable = 'off';
                app.Button_New_ODF_PF.Enable = 'off';
                app.UI_Enable(app.UI_GROUPS.PF, 'off');
                app.UI_Enable(app.UI_GROUPS.Contour, 'off');
                app.UI_Enable(app.UI_GROUPS.Active, 'off');
                app.Button_Delete_ODF_PF.Text = 'Clear: ';
                app.Button_New_ODF_PF.Text = 'Click the ODF';
            end

            if length(app.Tree_Workspace.SelectedNodes) == 1

                if startsWith(app.Tree_Workspace.SelectedNodes.Tag, 'ODF')
                    app.UI_Enable(app.UI_GROUPS.ODF, 'on');
                    app.UI_Enable(app.UI_GROUPS.PF, 'off');
                    app.UI_Enable(app.UI_GROUPS.Contour, 'off');
                    app.UI_Enable(app.UI_GROUPS.Active, 'off');
                    app.Button_Delete_ODF_PF.Enable = 'on';
                    app.Button_New_ODF_PF.Enable = 'on';
                    app.Button_Delete_ODF_PF.Text = 'Clear: ODF';
                    app.Button_New_ODF_PF.Text = 'New: Pole';

                elseif startsWith(app.Tree_Workspace.SelectedNodes.Tag, 'PF')

                    app.UI_Enable(app.UI_GROUPS.ODF, 'on');
                    app.UI_Enable(app.UI_GROUPS.PF, 'on');

                    if length(allchild(selectedNodes)) > 1
                        app.UI_Enable(app.UI_GROUPS.Contour, 'on');
                        app.UI_Enable(app.UI_GROUPS.Active, 'on');
                    end

                    app.Button_Delete_ODF_PF.Enable = 'on';
                    app.Button_New_ODF_PF.Enable = 'on';
                    app.Button_Delete_ODF_PF.Text = 'Clear: Pole';
                    app.Button_New_ODF_PF.Text = 'New: Pole';

                    app.DropDown_Levels.Items = arrayfun(@(x) num2str(x), selectedNodes.Children(1).NodeData, 'UniformOutput', false);
                    app.DropDown_Levels.Value = num2str(min(selectedNodes.Children(1).NodeData));
                    app.CURRENT_LEVEL = str2double(app.DropDown_Levels.Value);

                    if endsWith(selectedNodes.Tag, '_New')
                        selectedNodes.Tag = 'PF';
                    else
                        app.DropDown_Pole.Value = selectedNodes.NodeData;
                        app.ActivePoleValue.Text = selectedNodes.NodeData.char;
                        t = sprintf(' %g,', selectedNodes.Children(1).NodeData);
                        levels_text = sprintf('%s', t);
                        levels_text(end) = [];
                        app.EditField_DefineLevels.Value = levels_text;
                        app.DropDown_Levels.Items = arrayfun(@(x) num2str(x), selectedNodes.Children(1).NodeData, 'UniformOutput', false);
                        app.DropDown_Levels.Value = num2str(min(selectedNodes.Children(1).NodeData));
                        app.CURRENT_LEVEL = str2double(app.DropDown_Levels.Value);
                        app.ActiveLevelValue.Text = num2str(app.CURRENT_LEVEL);
                        if length(allchild(selectedNodes)) > 1
                            app.EditField_FindImage.Value = selectedNodes.Children(2).NodeData.File.location;
                            cla(app.FIG.Axes,"reset");
                            imshow(selectedNodes.Children(2).NodeData.IM, 'Parent', app.FIG.Axes, 'Border', 'tight', 'InitialMagnification', 'fit');
                            if ~isempty(selectedNodes.Children(2).NodeData.BC)
                                selectedNodes.Children(2).NodeData.BC.plot(app.FIG.Axes)
                            end
                        end
                    end
                else
                    app.Tree_Workspace.SelectedNodes = selectedNodes.Parent;
                    app.refresh_app;
                end
            end
        end
    end

    methods (Access = public)

        function UI_Enable(app, group, status)

            if any(~isgraphics(group))
                error('Not a graphics array');
            end

            if ~ismember(status, {'on', 'off', 'inactive'})
                error('Not a valid status')
            end

            for i = 1:length(group)
                group(i).Enable = status;
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             if ~(isdeployed || ismcc)
            %                 addpath(genpath(fullfile(pwd, 'Functions/')));
            %
            %                 %                 %Begley is lazy and doesn't want to remember to load MTEX. TODO: Remove
            %                 %                 maxLoadAttempts = 3;
            %                 %                 ii = 0;
            %                 %                 while ii < maxLoadAttempts
            %                 %                     try
            %                 %                         crystalSymmetry('cubic');
            %                 %                         break;
            %                 %                     catch
            %                 %                         ii = ii + 1;
            %                 %                         old = cd(app.mtexDir);
            %                 %                         startup;
            %                 %                         cd(old);
            %                 %                     end
            %                 %                 end
            %             end

            app.set_constants;
            app.set_ui_groups;

            app.SAVE_STATE = struct();
            app.SAVE_STATE.nSessionODFs = 0;
            app.SAVE_STATE.nODFs = 0;

            app.createODFnode;
            app.Tree_Workspace.SelectedNodes = app.Tree_Workspace.Children(1);

            s = sprintf('Lattice: %s', app.DropDown_LatticeType.Value);
            v = crystalSymmetry(app.DropDown_LatticeType.Value);
            [app.DropDown_Pole.Items, app.DropDown_Pole.ItemsData] = ...
                get_miller_options_from_lattice(v);
            app.createInfoNode(app.Tree_Workspace.SelectedNodes, s, v);

            s = sprintf('Projection: %s', app.DropDown_ProjectionType.Value);
            v = app.DropDown_ProjectionType.Value;
            app.createInfoNode(app.Tree_Workspace.SelectedNodes, s, v);

            s = sprintf('Method: %s', app.DropDown_MethodType.Value);
            v = app.DropDown_MethodType.Value;
            app.createInfoNode(app.Tree_Workspace.SelectedNodes, s, v);

            expand(app.Tree_Workspace.SelectedNodes);

            app.STARTUP_COMPLETE = true;
        end

        % Button pushed function: Button_FindImage
        function Button_FindImagePushed(app, event)

            pf = app.Tree_Workspace.SelectedNodes;

            if ~(startsWith(pf.Tag, 'PF'))
                uialert(app.MainWindow, 'Select a Pole Figure to add image', 'Invalid Action');
                return;
            end

            if length(allchild(pf)) > 1
                msg = {'$\textbf{Pole figure already has an image:}',...
                    '',...
                    '$\textbf{Replace}$ will delete all data in the current images.'};
                title = 'Replace Image?';
                selection = uiconfirm(app.MainWindow, msg, title, ...
                    'Options', {'Replace', 'Cancel'}, ...
                    'DefaultOption', 1, 'CancelOption',2, ...
                    'Icon', 'question', 'Interpreter', 'latex');

                if strcmp(selection, 'Replace')
                    child_handles = allchild(pf);
                    delete(child_handles(2:end));
                    app.createIMnode(pf);
                    return;
                end

                return;

            end

            app.createIMnode(pf);
            app.refresh_app;

        end

        % Callback function
        function Button_SetPolePushed(app, event)
            if isempty(app.EditField_SetPole)
                app.EditField_SetPole.Placeholder = 'You have not updated the pole yet!';
            else
                app.POLE = app.EditField_SetPole.Value;
                app.ActivePoleValue.Text = sprintf('$\\mathbf{\\langle %s \\rangle}$', app.POLE);
            end
        end

        % Callback function
        function Button_SetExtremumPushed(app, event)
            app.CURRENT_EXT = Extrema(app.DropDown_SetExtremum.Value);
            app.ActiveExtremumValue.Text = app.CURRENT_EXT.char;
        end

        % Callback function
        function Button_TraverseLevelsPushed(app, event)
            app.CURRENT_LEVEL = str2double(app.DropDown_Levels.Value);
            app.ActiveLevelValue.Text = string(app.DropDown_Levels.Value);
        end

        % Button pushed function: Button_CollectPoints
        function Button_CollectPointsPushed(app, event)
            cmap = parula(256);
            lvls = app.Tree_Workspace.SelectedNodes.Children(1).NodeData;
            clims = [min(lvls) max(lvls)];

            rgb = @(x) cmap(round(interp1(clims, [1 length(cmap)], x)), :);

            [vert_x, vert_y, ~] = click_at_level(app.FIG.Axes, rgb(app.CURRENT_LEVEL), app.CURRENT_EXT);

            app.Tree_Workspace.SelectedNodes.Children(3).NodeData.P.append(Point(vert_x,...
                vert_y,...
                repmat(app.CURRENT_LEVEL, 1, length(vert_x)), ...
                repmat(app.CURRENT_EXT, 1, length(vert_x))));

            s = sprintf('Points: %d', size(app.Tree_Workspace.SelectedNodes.Children(3).NodeData.P, 2));
            set(app.Tree_Workspace.SelectedNodes.Children(3), 'Text', s);
        end

        % Button pushed function: Button_SavePoints
        function Button_SavePointsPushed(app, event)
            data = app.Tree_Workspace.SelectedNodes.Children(3).NodeData.P;
            bc = app.Tree_Workspace.SelectedNodes.Children(2).NodeData.BC;
            im = app.Tree_Workspace.SelectedNodes.Children(2).NodeData.IM;

            DSET = 'CubicDemo';
            DATA = app.Tree_Workspace.SelectedNodes.NodeData.char;
            USER = 'JohnDoe';
            WHEN = datestr(now, 'yyyy-mm-dd-HHMM' );

            str = sprintf('%s_%s_%s_%s', DSET, DATA, USER, WHEN);
            f = fullfile(pwd, 'reconstructions', [str '.mat']);


            uisave({'data', 'bc', 'im'}, f);
            clear('im', 'data', 'bc');
        end

        % Size changed function: MainWindow
        function MainWindowSizeChanged(app, event)
            if app.STARTUP_COMPLETE
                app.DEFAULTS.ButtonFontSize = app.Button_New_ODF_PF.FontSize;
                app.DEFAULTS.TitleFontSize = app.Label_Title.FontSize;
            end
        end

        % Value changed function: DropDown_LatticeType
        function DropDown_LatticeTypeValueChanged(app, event)

            s = sprintf('Lattice: %s', app.DropDown_LatticeType.Value);
            v = crystalSymmetry(app.DropDown_LatticeType.Value);
            set(app.Tree_Workspace.Children(1).Children(1), 'Text', s)
            set(app.Tree_Workspace.Children(1).Children(1), 'NodeData', v)
            [app.DropDown_Pole.Items, app.DropDown_Pole.ItemsData] = get_miller_options_from_lattice(v);
        end

        % Value changed function: DropDown_Pole
        function DropDown_PoleValueChanged(app, event)
            s = sprintf('%s Pole', app.DropDown_Pole.Value);
            cs = app.Tree_Workspace.Children(1).Children(1).NodeData;
            txt = Miller(app.DropDown_Pole.Value, cs);
            set(app.Tree_Workspace.SelectedNodes, 'Text', s);
            set(app.Tree_Workspace.SelectedNodes, 'NodeData', txt);
            set(app.ActivePoleValue, 'Text', app.Tree_Workspace.SelectedNodes.NodeData.char);
        end

        % Value changed function: StateButton_Minima
        function StateButton_MinimaValueChanged(app, event)
            app.swap_extrema(app.StateButton_Minima);
            app.CURRENT_EXT = Extrema.Min;
            app.ActiveExtremumValue.Text = app.CURRENT_EXT.char;
        end

        % Value changed function: StateButton_Maxima
        function StateButton_MaximaValueChanged(app, event)
            app.swap_extrema(app.StateButton_Maxima);
            app.CURRENT_EXT = Extrema.Max;
            app.ActiveExtremumValue.Text = app.CURRENT_EXT.char;
        end

        % Value changed function: StateButton_Neither
        function StateButton_NeitherValueChanged(app, event)
            app.swap_extrema(app.StateButton_Neither)
            app.CURRENT_EXT = Extrema.Not;
            app.ActiveExtremumValue.Text = app.CURRENT_EXT.char;
        end

        % Button pushed function: Button_New_ODF_PF
        function Button_New_ODF_PFPushed(app, event)
            node = app.createPFnode;
            expand(node);
            %app.Tree_Workspace.SelectedNodes = node;
            app.refresh_app;
        end

        % Selection changed function: Tree_Workspace
        function Tree_WorkspaceSelectionChanged(app, event)

            app.refresh_app();

        end

        % Value changed function: DropDown_MethodType
        function DropDown_MethodTypeValueChanged(app, event)
            s = sprintf('Method: %s', app.DropDown_MethodType.Value);
            v = app.DropDown_MethodType.Value;
            set(app.Tree_Workspace.Children(1).Children(3), 'Text', s)
            set(app.Tree_Workspace.Children(1).Children(3), 'NodeData', v)
        end

        % Value changed function: DropDown_ProjectionType
        function DropDown_ProjectionTypeValueChanged(app, event)
            s = sprintf('Projection: %s', app.DropDown_ProjectionType.Value);
            v = app.DropDown_ProjectionType.Value;
            set(app.Tree_Workspace.Children(1).Children(2), 'Text', s)
            set(app.Tree_Workspace.Children(1).Children(2), 'NodeData', v)
        end

        % Button pushed function: Button_DefineLevels
        function Button_DefineLevelsPushed(app, event)
            levels = app.getLevelsFromField;
            t = sprintf(' %g', levels);
            levels_text = sprintf('Levels: [%s ]', t);

            set(app.Tree_Workspace.SelectedNodes.Children(1), 'Text', levels_text);
            set(app.Tree_Workspace.SelectedNodes.Children(1), 'NodeData', levels);

            app.DropDown_Levels.Items = arrayfun(@(x) num2str(x), levels, 'UniformOutput', false);
            app.DropDown_Levels.Value = num2str(min(levels));
            app.CURRENT_LEVEL = str2double(app.DropDown_Levels.Value);
        end

        % Button pushed function: Button_TransformImage
        function Button_TransformImagePushed(app, event)
            %invokeTutorial('1');
            debug_info = struct;
            ax = app.FIG.Axes;
            im = app.Tree_Workspace.SelectedNodes.Children(2).NodeData.IM;

            % get center
            [cx,cy] = ginput(1);
            cx = round(cx);
            cy = round(cy);
            debug_info.c = [cx cy];

            hold on;
            center = plot(ax, cx, cy, 'ro', 'MarkerSize', 10, 'Color', [1 0 0]);
            hold off;

            [ex,ey] = ginput(1);
            ex = round(ex);
            ey = round(ey);
            debug_info.e = [ex ey];

            hold on;
            edge = plot(ax, ex, ey, 'ro', 'MarkerSize', 10, 'Color', [1 0 0]);
            hold off;

            radius = round(norm([cx cy] - [ex ey]));
            debug_info.r = radius;

            delete(center);
            delete(edge);

            pad = round(1.2*radius);

            debug_info.size = size(im);
            debug_info.crop = [cx-pad cy-pad 2*pad 2*pad];

            image_size = size(im);
            crop_pad_pre  = [0 0];
            crop_pad_post = [0 0];

            if (cx-pad) < 0
                crop_pad_pre(2) = abs(cx-pad);
            end
            if (cy-pad) < 0
                crop_pad_pre(1) = abs(cy-pad);
            end
            if image_size(1)-(cy+pad) < 0
                crop_pad_post(1) = abs(image_size(1)-(cy+pad));
            end
            if image_size(2)-(cx+pad) < 0
                crop_pad_post(2) = abs(image_size(1)-(cx+pad));
            end

            imshow(im, 'Parent', app.FIG.Axes, 'Border', 'tight', 'InitialMagnification', 'fit');

            im = imcrop(im, [cx-pad cy-pad 2*pad 2*pad]);

            im = padarray(im, crop_pad_pre, 0, 'pre');
            im = padarray(im, crop_pad_post, 0, 'post');

            cx = cx+crop_pad_pre(2);
            cy = cy+crop_pad_pre(1);

            imshow(im, 'Parent', app.FIG.Axes, 'Border', 'tight', 'InitialMagnification', 'fit');

            bc = BoundaryCircle(cx,cy,radius,0:0.01:2*pi);
            bc.plot(ax);

            V = size(im);

            bc.x0 = V(2)/2;
            bc.y0 = V(2)/2;

            imshow(im, 'Parent', app.FIG.Axes, 'Border', 'tight', 'InitialMagnification', 'fit');

            bc.plot(ax);

            app.Tree_Workspace.SelectedNodes.Children(2).NodeData.BC = bc;
            app.Tree_Workspace.SelectedNodes.Children(2).NodeData.IM = im;
        end

        % Value changed function: DropDown_Levels
        function DropDown_LevelsValueChanged(app, event)
            app.CURRENT_LEVEL = str2double(app.DropDown_Levels.Value);
            app.ActiveLevelValue.Text = num2str(app.CURRENT_LEVEL);
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            delete(app)
            close all;
        end

        % Button pushed function: Button_Preview
        function Button_PreviewPushed(app, event)
            data = app.Tree_Workspace.SelectedNodes.Children(3).NodeData.P;
            bc = app.Tree_Workspace.SelectedNodes.Children(2).NodeData.BC;

            grid = HEALPixS2Grid('resolution', 1.5*degree);
            proj_string = app.Tree_Workspace.Children(1).Children(2).NodeData;
            proj = app.getProjection;
            [projective_scaling, ~] = proj.project(vector3d(1,0,0));
            CS = app.Tree_Workspace.SelectedNodes.Parent.Children(1).NodeData;
            SS = specimenSymmetry('-1');
            h = Miller(app.Tree_Workspace.SelectedNodes.NodeData, CS);

            scale = [bc.x0 bc.y0];
            points_centered = zeros(2, length(data.x));

            DRAGON_pos_shift = [-scale(1), -scale(2)];

            points_centered(1,:) = data.y + DRAGON_pos_shift(1);
            points_centered(2,:) = data.x + DRAGON_pos_shift(2);

            %Pole Distribution Function Intensity
            intensity = data.val;

            % point_type: Min, Max, or Contour-representative.
            point_type = Extrema(int8(data.ext));
            minima = point_type.isMin;
            maxima = point_type.isMax;
            contour_representative = ~point_type.isExtreme;
            % radius of to-be-digitized pole figure (nominally pixels)
            radius = bc.a;

            clear('data', 'bc');

            points_centered = points_centered .* (projective_scaling / radius);
            nodes = proj.iproject(points_centered(1,:), points_centered(2,:));

            clear('points_centered');

            nodes_antipodes = nodes .* -1;
            nodes = horzcat(nodes, nodes_antipodes);
            intensity = repmat(intensity,1,2);
            minima = repmat(minima,1,2);
            maxima = repmat(maxima,1,2);
            contour_representative = repmat(contour_representative,1,2);

            figure('Name', 'User-clicked points colored by PDF intensity');
            scatter(nodes(contour_representative),intensity(contour_representative),...
                'Marker', '.', 'upper','projection',proj_string, 'MarkerSize', 20, 'add2all');
            scatter(nodes(minima),intensity(minima),...
                'Marker', 'v', 'upper','projection',proj_string, 'MarkerSize', 10, 'add2all');
            scatter(nodes(maxima),intensity(maxima),...
                'Marker', '^', 'upper','projection',proj_string, 'MarkerSize', 10, 'add2all');
            scatter(nodes, 'Marker', '.', 'upper','projection',proj_string,...
                'MarkerSize', 7, 'MarkerColor', 'k', 'add2all');
            mtexColorMap('parula');
            mtexColorbar('Title', 'Multiples of Random');
            setColorRange([min(intensity) max(intensity)]);

            clear('minima', 'maxima', 'contour_representative');

            if strcmp(app.Tree_Workspace.Children(1).Children(3).NodeData, 'interp')
                sf = interp(nodes, intensity, 'linear');
            elseif strcmp(app.Tree_Workspace.Children(1).Children(3).NodeData, 'harmonic')
                sFHarm_grid = interp(nodes, intensity, grid, 'harmonic');
                %sf = S2FunTri(grid, sFHarm_grid);
                sf = S2FunHarmonic.approximation(grid, sFHarm_grid);
            end
            clear('nodes');
            intensity = [min(intensity) max(intensity)];

            figure;
            plot(sf, 'upper', 'projection', proj_string);
            hold on;
            plot(sf, 'contour', app.Tree_Workspace.SelectedNodes.Children(1).NodeData, 'linecolor', 'black', 'ShowText', 'on', 'upper', 'projection', proj_string);
            hold off;
            mtexColorMap('parula');
            mtexColorbar;
            setColorRange([min(intensity) max(intensity)]);

%             figure;
%             surf(sf);
%             mtexColorMap('parula');
%             mtexColorbar;
%             setColorRange([min(intensity) max(intensity)]);


            pf_grid = PoleFigure(h, grid, sf.eval(grid), CS, SS);

            figure;
            plot(pf_grid,h, 'projection', proj_string)
            hold on
            plot(pf_grid, h, 'contour', app.Tree_Workspace.SelectedNodes.Children(1).NodeData, 'linecolor', 'black', 'ShowText', 'on', 'upper', 'projection', proj_string);
            hold off
            mtexColorMap('parula');
            mtexColorbar;
            setColorRange([min(intensity) max(intensity)]);
        end

        % Button pushed function: Button_SetLatticeType_4
        function Button_ComputeODFPushed(app, event)
            tic
            hh = cell(1,3);
            rr = cell(1,3);
            ii = cell(1,3);

            for jj = 4:length(app.Tree_Workspace.Children(1).Children)
                fprintf('Starting Pole Figure %d...\n', jj-3)
                sn = app.Tree_Workspace.Children(1).Children(jj);
                data = sn.Children(3).NodeData.P;
                bc = sn.Children(2).NodeData.BC;

                grid = HEALPixS2Grid('resolution', 1.5*degree);
                proj_string = app.Tree_Workspace.Children(1).Children(2).NodeData;
                proj = app.getProjection;
                [projective_scaling, ~] = proj.project(vector3d(1,0,0));
                CS = app.Tree_Workspace.Children(1).Children(1).NodeData;
                SS = specimenSymmetry('-1');
                h = Miller(sn.NodeData, CS);

                scale = [bc.x0 bc.y0];
                points_centered = zeros(2, length(data.x));

                DRAGON_pos_shift = [-scale(1), -scale(2)];

                points_centered(1,:) = data.y + DRAGON_pos_shift(1);
                points_centered(2,:) = data.x + DRAGON_pos_shift(2);

                %Pole Distribution Function Intensity
                intensity = data.val;

                % point_type: Min, Max, or Contour-representative.
                point_type = Extrema(int8(data.ext));
                minima = point_type.isMin;
                maxima = point_type.isMax;
                contour_representative = ~point_type.isExtreme;
                % radius of to-be-digitized pole figure (nominally pixels)
                radius = bc.a;

                points_centered = points_centered .* (projective_scaling / radius);
                nodes = proj.iproject(points_centered(1,:), points_centered(2,:));

                nodes_antipodes = nodes .* -1;
                nodes = horzcat(nodes, nodes_antipodes);
                intensity = repmat(intensity,1,2);
                minima = repmat(minima,1,2);
                maxima = repmat(maxima,1,2);
                contour_representative = repmat(contour_representative,1,2);

                if strcmp(app.Tree_Workspace.Children(1).Children(3).NodeData, 'interp')
                    sf = interp(nodes, intensity, 'linear');
                elseif strcmp(app.Tree_Workspace.Children(1).Children(3).NodeData, 'harmonic')
                    sFHarm_grid = interp(nodes, intensity, grid, 'harmonic');
                    %sf = S2FunTri(grid, sFHarm_grid);
                    sf = S2FunHarmonic.approximation(grid, sFHarm_grid);
                end

                pf_grid = PoleFigure(h, grid, sf.eval(grid), CS, SS);
                hh{jj-3} = pf_grid.h;
                rr{jj-3} = pf_grid.r;
                ii{jj-3} = pf_grid.intensities;

                fprintf('Finishing Pole Figure %d...\n', jj)
            end

            psi = deLaValleePoussinKernel('halfwidth', 6*degree);
            %psi = bumpKernel(6*degree);

            fprintf('Calculating combined pole figure...\n')
            app.PF = PoleFigure(hh,rr,ii);

            fprintf('Calculating combined ODF...\n')
            [app.ODF, app.ALPHA] = calcODF(app.PF, psi,'zero_range', 'itermin', 100, 'iterMax', 200);
            disp(app.ALPHA)

            fprintf('Finished!\n')
            toc

            odf = app.ODF;
            pf = app.PF;
            alpha = app.ALPHA;
            DSET = 'CubicDemo';
            DATA = 'Final';
            MTHD = app.DropDown_MethodType.Value;
            USER = 'JohnDoe';
            WHEN = datestr(now, 'yyyy-mm-dd-HHMM' );

            str = sprintf('%s_%s_%s_%s_%s', DSET, MTHD, DATA, USER, WHEN);
            f = fullfile(pwd, 'reconstructions', [str '.mat']);

            uisave({'odf', 'pf', 'alpha'}, f);

            clear('odf', 'pf', 'alpha', 'str', 'f');

            answer = questdlg('Do you wish to plot the Pole Figure and ODF?', 'Plot Results?', 'Yes');

            if strcmp(answer, 'Yes')
                figure;
                plot(app.PF, 'upper', 'projection', proj_string);
                plot(app.PF, 'contour', app.Tree_Workspace.Children(1).Children(4).Children(1).NodeData, 'linecolor', 'black', 'ShowText', 'on', 'upper', 'projection', proj_string, 'add2all')
                mtexColorMap('parula');
                mtexColorbar;
                setColorRange([min(app.Tree_Workspace.Children(1).Children(4).Children(1).NodeData)...
                    max(app.Tree_Workspace.Children(1).Children(4).Children(1).NodeData)]);

                figure;
                plotPDF(app.ODF,hh,'upper', 'projection', proj_string);
                hold on;
                plotPDF(app.ODF,hh,'contour', 'linecolor', 'black', 'ShowText', 'off', 'upper', 'projection', proj_string)
                hold off;
                mtexColorMap('parula');
                mtexColorbar;
                %setColorRange([min(app.Tree_Workspace.SelectedNodes.Children(1).NodeData) max(app.Tree_Workspace.SelectedNodes.Children(1).NodeData)]);
            end


        end

        % Button pushed function: Button_LoadPoints
        function Button_LoadPointsPushed(app, event)
            uiopen("load");
            app.Tree_Workspace.SelectedNodes.Children(3).NodeData.P = data;
            app.Tree_Workspace.SelectedNodes.Children(2).NodeData.BC = bc;
            app.Tree_Workspace.SelectedNodes.Children(2).NodeData.IM = im;

            s = sprintf('Points: %d', size(app.Tree_Workspace.SelectedNodes.Children(3).NodeData.P, 2));
            set(app.Tree_Workspace.SelectedNodes.Children(3), 'Text', s);
            clear('data', 'bc', 'im');
        end

        % Button pushed function: Button_MovePoints
        function Button_TestHarmonicBandwidth(app, event)
            data = app.Tree_Workspace.SelectedNodes.Children(3).NodeData.P;
            bc = app.Tree_Workspace.SelectedNodes.Children(2).NodeData.BC;

            grid = HEALPixS2Grid('resolution', 1.5*degree);
            proj_string = app.Tree_Workspace.Children(1).Children(2).NodeData;
            proj = app.getProjection;
            [projective_scaling, ~] = proj.project(vector3d(1,0,0));
            CS = app.Tree_Workspace.SelectedNodes.Parent.Children(1).NodeData;
            SS = specimenSymmetry('-1');
            h = Miller(app.Tree_Workspace.SelectedNodes.NodeData, CS);

            scale = [bc.x0 bc.y0];
            points_centered = zeros(2, length(data.x));

            DRAGON_pos_shift = [-scale(1), -scale(2)];

            points_centered(1,:) = data.y + DRAGON_pos_shift(1);
            points_centered(2,:) = data.x + DRAGON_pos_shift(2);

            %Pole Distribution Function Intensity
            intensity = data.val;

            % point_type: Min, Max, or Contour-representative.
            point_type = Extrema(int8(data.ext));
            minima = point_type.isMin;
            maxima = point_type.isMax;
            contour_representative = ~point_type.isExtreme;
            % radius of to-be-digitized pole figure (nominally pixels)
            radius = bc.a;

            clear('data', 'bc');

            points_centered = points_centered .* (projective_scaling / radius);
            nodes = proj.iproject(points_centered(1,:), points_centered(2,:));

            clear('points_centered');

            nodes_antipodes = nodes .* -1;
            nodes = horzcat(nodes, nodes_antipodes);
            intensity = repmat(intensity,1,2);

            clear('minima', 'maxima', 'contour_representative');

            sFHarm_grid = interp(nodes, intensity, grid, 'harmonic');
            %sf = S2FunTri(grid, sFHarm_grid);
            sf = S2FunHarmonic.approximation(grid, sFHarm_grid);

            clear('nodes');
            intensity = [min(intensity) max(intensity)];

            for bw = [256 128 64 32 16 8]
                sf.bandwidth = bw;
                nextAxis;
                plot(sf, 'upper', 'projection', proj_string);
                hold on;
                plot(sf, 'contour', app.Tree_Workspace.SelectedNodes.Children(1).NodeData, 'linecolor', 'black', 'ShowText', 'on', 'upper', 'projection', proj_string);
                hold off;
                mtexColorMap('parula');
                mtexColorbar;
                setColorRange([min(intensity) max(intensity)]);
                mtexTitle(['M = ' num2str(bw)]);
            end

        end

        % Button pushed function: Button_Save_ODF_PF
        function Button_Save_ODF_PFPushed(app, event)
            odf = app.ODF;
            pf = app.PF;
            alpha = app.ALPHA;

            DSET = 'CubicDemo';
            DATA = 'Final';
            MTHD = app.DropDown_MethodType.Value;
            USER = 'JohnDoe';
            WHEN = datestr(now, 'yyyy-mm-dd-HHMM' );

            str = sprintf('%s_%s_%s_%s_%s', DSET, MTHD, DATA, USER, WHEN);
            f = fullfile(pwd, 'reconstructions', [str '.mat']);

            uisave({'odf', 'pf', 'alpha'}, f);

            clear('odf', 'pf', 'alpha', 'str', 'f');
        end

        % Button pushed function: Button_Delete_ODF_PF
        function Button_Delete_ODF_PFPushed(app, event)
            selectedNodes = app.Tree_Workspace.SelectedNodes;

            if startsWith(app.Tree_Workspace.SelectedNodes.Tag, 'ODF')
                child_handles = allchild(app.Tree_Workspace.SelectedNodes);
                delete(child_handles(4:end));
                expand(app.Tree_Workspace.SelectedNodes)
            end

            if startsWith(app.Tree_Workspace.SelectedNodes.Tag, 'PF')
                delete(selectedNodes);

                %app.Tree_Workspace.SelectedNodes = app.Tree_Workspace.Children(1);

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'MATLAB App';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.MainWindow.SizeChangedFcn = createCallbackFcn(app, @MainWindowSizeChanged, true);
            app.File = uimenu(app.MainWindow);
            app.File.Text = 'File';
            app.NewMenu = uimenu(app.File);
            app.NewMenu.Text = 'New';
            app.ODFMenu = uimenu(app.NewMenu);
            app.ODFMenu.Text = 'ODF';
            app.PoleFigureMenu = uimenu(app.NewMenu);
            app.PoleFigureMenu.Text = 'Pole Figure';
            app.SaveWorkspaceMenu = uimenu(app.File);
            app.SaveWorkspaceMenu.Text = 'Save Workspace';
            app.SavePointstoFileMenu = uimenu(app.File);
            app.SavePointstoFileMenu.Text = 'Save Points to File';
            app.Edit = uimenu(app.MainWindow);
            app.Edit.Text = 'Edit';
            app.BaseGrid = uigridlayout(app.MainWindow);
            app.EditField_FindImage = uieditfield(app.BaseGrid, 'text');
            app.EditField_FindImage.Tag = 'gPF_ImagePathField';
            app.EditField_FindImage.Enable = 'off';
            app.EditField_FindImage.Placeholder = 'C:\Users\Cool_Files';
            app.Button_FindImage = uibutton(app.BaseGrid, 'push');
            app.Button_FindImage.ButtonPushedFcn = createCallbackFcn(app, @Button_FindImagePushed, true);
            app.Button_FindImage.Tag = 'gPF_LoadImageButton';
            app.Button_FindImage.Enable = 'off';
            app.Button_FindImage.Text = 'Load Image';
            app.Button_CollectPoints = uibutton(app.BaseGrid, 'push');
            app.Button_CollectPoints.ButtonPushedFcn = createCallbackFcn(app, @Button_CollectPointsPushed, true);
            app.Button_CollectPoints.Tag = 'gCon_CollectPointsButton';
            app.Button_CollectPoints.Enable = 'off';
            app.Button_CollectPoints.Text = 'Collect Points';
            app.DropDown_Levels = uidropdown(app.BaseGrid);
            app.DropDown_Levels.Items = {};
            app.DropDown_Levels.ValueChangedFcn = createCallbackFcn(app, @DropDown_LevelsValueChanged, true);
            app.DropDown_Levels.Tag = 'gCon_LevelDropDown';
            app.DropDown_Levels.Enable = 'off';
            app.DropDown_Levels.Value = {};
            app.Tree_Workspace = uitree(app.BaseGrid);
            app.Tree_Workspace.SelectionChangedFcn = createCallbackFcn(app, @Tree_WorkspaceSelectionChanged, true);
            app.Tree_Workspace.Tag = 'CoolGrey11';
            app.DropDown_LatticeType = uidropdown(app.BaseGrid);
            app.DropDown_LatticeType.Items = {'Cubic', 'Hexagonal', 'Tetragonal', 'Trigonal', 'Orthorhombic', 'Monoclinic', 'Triclinic'};
            app.DropDown_LatticeType.ItemsData = {'cubic', 'hexagonal', 'tetragonal', 'trigonal', 'orthorhombic', 'monoclinic', 'triclinic'};
            app.DropDown_LatticeType.ValueChangedFcn = createCallbackFcn(app, @DropDown_LatticeTypeValueChanged, true);
            app.DropDown_LatticeType.Tag = 'gODF_LatticeDropDown';
            app.DropDown_LatticeType.Value = 'cubic';
            app.DropDown_Pole = uidropdown(app.BaseGrid);
            app.DropDown_Pole.Items = {};
            app.DropDown_Pole.ValueChangedFcn = createCallbackFcn(app, @DropDown_PoleValueChanged, true);
            app.DropDown_Pole.Tag = 'gPF_PoleDropDown';
            app.DropDown_Pole.Enable = 'off';
            app.DropDown_Pole.Value = {};
            app.ActiveLevelValue = uilabel(app.BaseGrid);
            app.ActiveLevelValue.Tag = 'gAct_LevelValue';
            app.ActiveLevelValue.Enable = 'off';
            app.ActiveLevelValue.Text = '';
            app.ActiveExtremumValue = uilabel(app.BaseGrid);
            app.ActiveExtremumValue.Tag = 'gAct_ExtremumValue';
            app.ActiveExtremumValue.Enable = 'off';
            app.ActiveExtremumValue.Text = {''; ''};
            app.ActivePoleValue = uilabel(app.BaseGrid);
            app.ActivePoleValue.Interpreter = 'latex';
            app.ActivePoleValue.Tag = 'gAct_PoleValue';
            app.ActivePoleValue.Enable = 'off';
            app.ActivePoleValue.Text = '';
            app.Label_ActiveLevel = uilabel(app.BaseGrid);
            app.Label_ActiveLevel.Tag = 'gAct_LevelLabel';
            app.Label_ActiveLevel.Enable = 'off';
            app.Label_ActiveLevel.Text = {'Level'; ''};
            app.Label_ActiveExtremum = uilabel(app.BaseGrid);
            app.Label_ActiveExtremum.Tag = 'gAct_ExtremumLabel';
            app.Label_ActiveExtremum.Enable = 'off';
            app.Label_ActiveExtremum.Text = {'Extremum'; ''};
            app.Label_ActivePole = uilabel(app.BaseGrid);
            app.Label_ActivePole.Tag = 'gAct_PoleLabel';
            app.Label_ActivePole.Enable = 'off';
            app.Label_ActivePole.Text = 'Pole';
            app.DropDown_LevelsAs = uidropdown(app.BaseGrid);
            app.DropDown_LevelsAs.Items = {'As List', 'As Linspace', ''};
            app.DropDown_LevelsAs.ItemsData = {'list', 'linspace', ''};
            app.DropDown_LevelsAs.Tag = 'gPF_LevelsAsDropDown';
            app.DropDown_LevelsAs.Enable = 'off';
            app.DropDown_LevelsAs.Tooltip = {'List: Comma delimited (i.e. 1, 2, 3)'; ''; 'Linspace: Colon delimited + optional comma delimited list (Start:Step:End + 1,2,3)'};
            app.DropDown_LevelsAs.Value = 'list';
            app.Button_DefineLevels = uibutton(app.BaseGrid, 'push');
            app.Button_DefineLevels.ButtonPushedFcn = createCallbackFcn(app, @Button_DefineLevelsPushed, true);
            app.Button_DefineLevels.Tag = 'gPF_DefineLevelsButton';
            app.Button_DefineLevels.Enable = 'off';
            app.Button_DefineLevels.Text = 'Define Levels';
            app.EditField_DefineLevels = uieditfield(app.BaseGrid, 'text');
            app.EditField_DefineLevels.Tag = 'gPF_LevelsEditField';
            app.EditField_DefineLevels.Enable = 'off';
            app.EditField_DefineLevels.Tooltip = {'Spaces between the numbers helps parse it.'};
            app.EditField_DefineLevels.Value = '1, 2, 3, 4';
            app.Button_New_ODF_PF = uibutton(app.BaseGrid, 'push');
            app.Button_New_ODF_PF.ButtonPushedFcn = createCallbackFcn(app, @Button_New_ODF_PFPushed, true);
            app.Button_New_ODF_PF.Tag = 'DarkBlue';
            app.Button_New_ODF_PF.Text = 'New: Pole';
            app.Button_Delete_ODF_PF = uibutton(app.BaseGrid, 'push');
            app.Button_Delete_ODF_PF.ButtonPushedFcn = createCallbackFcn(app, @Button_Delete_ODF_PFPushed, true);
            app.Button_Delete_ODF_PF.Tag = 'DarkBlue';
            app.Button_Delete_ODF_PF.Enable = 'off';
            app.Button_Delete_ODF_PF.Text = {'Delete:'; ''};
            app.Button_Save_ODF_PF = uibutton(app.BaseGrid, 'push');
            app.Button_Save_ODF_PF.ButtonPushedFcn = createCallbackFcn(app, @Button_Save_ODF_PFPushed, true);
            app.Button_Save_ODF_PF.Tag = 'DarkBlue';
            app.Button_Save_ODF_PF.Text = 'Save ';
            app.Button_TransformImage = uibutton(app.BaseGrid, 'push');
            app.Button_TransformImage.ButtonPushedFcn = createCallbackFcn(app, @Button_TransformImagePushed, true);
            app.Button_TransformImage.Tag = 'gPF_TransformImageButton';
            app.Button_TransformImage.Enable = 'off';
            app.Button_TransformImage.Text = 'Transform Image';
            app.DropDown_ProjectionType = uidropdown(app.BaseGrid);
            app.DropDown_ProjectionType.Items = {'Equal Angle', 'Equal Distance', 'Equal Area'};
            app.DropDown_ProjectionType.ItemsData = {'eangle', 'edist', 'earea'};
            app.DropDown_ProjectionType.ValueChangedFcn = createCallbackFcn(app, @DropDown_ProjectionTypeValueChanged, true);
            app.DropDown_ProjectionType.Tag = 'gODF_ProjectionDropDown';
            app.DropDown_ProjectionType.Value = 'eangle';
            app.DropDown_MethodType = uidropdown(app.BaseGrid);
            app.DropDown_MethodType.Items = {'Linear Interp', 'Spherical Harmonic'};
            app.DropDown_MethodType.ItemsData = {'interp', 'harmonic'};
            app.DropDown_MethodType.ValueChangedFcn = createCallbackFcn(app, @DropDown_MethodTypeValueChanged, true);
            app.DropDown_MethodType.Tag = 'gODF_MethodDropDown';
            app.DropDown_MethodType.Value = 'interp';
            app.Button_SetLatticeType_4 = uibutton(app.BaseGrid, 'push');
            app.Button_SetLatticeType_4.ButtonPushedFcn = createCallbackFcn(app, @Button_ComputeODFPushed, true);
            app.Button_SetLatticeType_4.Tag = 'gODF_ButtonCompute';
            app.Button_SetLatticeType_4.Enable = 'off';
            app.Button_SetLatticeType_4.Text = 'Compute';
            app.Button_Preview = uibutton(app.BaseGrid, 'push');
            app.Button_Preview.ButtonPushedFcn = createCallbackFcn(app, @Button_PreviewPushed, true);
            app.Button_Preview.Tag = 'gPF_PreviewButton';
            app.Button_Preview.Enable = 'off';
            app.Button_Preview.Text = 'Preview';
            app.StateButton_Minima = uibutton(app.BaseGrid, 'state');
            app.StateButton_Minima.ValueChangedFcn = createCallbackFcn(app, @StateButton_MinimaValueChanged, true);
            app.StateButton_Minima.Tag = 'gCon_MinimaButton';
            app.StateButton_Minima.Enable = 'off';
            app.StateButton_Minima.Text = 'Minima';
            app.StateButton_Maxima = uibutton(app.BaseGrid, 'state');
            app.StateButton_Maxima.ValueChangedFcn = createCallbackFcn(app, @StateButton_MaximaValueChanged, true);
            app.StateButton_Maxima.Tag = 'gCon_MaximaButton';
            app.StateButton_Maxima.Enable = 'off';
            app.StateButton_Maxima.Text = 'Maxima';
            app.StateButton_Neither = uibutton(app.BaseGrid, 'state');
            app.StateButton_Neither.ValueChangedFcn = createCallbackFcn(app, @StateButton_NeitherValueChanged, true);
            app.StateButton_Neither.Tag = 'gCon_ContourButton';
            app.StateButton_Neither.Enable = 'off';
            app.StateButton_Neither.Text = 'Contour';
            app.Button_MovePoints = uibutton(app.BaseGrid, 'push');
            app.Button_MovePoints.ButtonPushedFcn = createCallbackFcn(app, @Button_TestHarmonicBandwidth, true);
            app.Button_MovePoints.Tag = 'gCon_MovePointsButton';
            app.Button_MovePoints.Enable = 'off';
            app.Button_MovePoints.Text = 'Test Harmonics';
            app.Button_SavePoints = uibutton(app.BaseGrid, 'push');
            app.Button_SavePoints.ButtonPushedFcn = createCallbackFcn(app, @Button_SavePointsPushed, true);
            app.Button_SavePoints.Tag = 'gCon_SavePointsButton';
            app.Button_SavePoints.Enable = 'off';
            app.Button_SavePoints.Text = 'Save Points';
            app.Button_LoadPoints = uibutton(app.BaseGrid, 'push');
            app.Button_LoadPoints.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadPointsPushed, true);
            app.Button_LoadPoints.Tag = 'gCon_LoadPointsButton';
            app.Button_LoadPoints.Enable = 'off';
            app.Button_LoadPoints.Text = 'Load Points';
            app.Label_Subtitle = uilabel(app.BaseGrid);
            app.Label_Subtitle.Text = 'monster.mse.ufl.edu';
            app.Label_Title = uilabel(app.BaseGrid);
            app.Label_Title.Text = {'DRAGON'; ''};
            app.WorkspaceLabel = uilabel(app.BaseGrid);
            app.WorkspaceLabel.Text = 'Workspace';
            app.Label_ActiveHeader = uilabel(app.BaseGrid);
            app.Label_ActiveHeader.Tag = 'gAct_Header';
            app.Label_ActiveHeader.Enable = 'off';
            app.Label_ActiveHeader.Text = 'Active';
            app.Label_ContourHeader = uilabel(app.BaseGrid);
            app.Label_ContourHeader.Tag = 'gCon_Header';
            app.Label_ContourHeader.Enable = 'off';
            app.Label_ContourHeader.Text = 'Contour';
            app.Label_PF_Header = uilabel(app.BaseGrid);
            app.Label_PF_Header.Tag = 'gPF_Header';
            app.Label_PF_Header.Enable = 'off';
            app.Label_PF_Header.Text = 'Pole Figure';
            app.Label_ODF_Header = uilabel(app.BaseGrid);
            app.Label_ODF_Header.Tag = 'gODF_gHeaders';
            app.Label_ODF_Header.Text = 'ODF';
            app.Label_Lattice = uilabel(app.BaseGrid);
            app.Label_Lattice.Tag = 'gODF_gLables_LatticeLabel';
            app.Label_Lattice.Text = 'Lattice';
            app.Label_Method = uilabel(app.BaseGrid);
            app.Label_Method.Tag = 'gODF_MethodLabel';
            app.Label_Method.Text = 'Method';
            app.Label_Projection = uilabel(app.BaseGrid);
            app.Label_Projection.Tag = 'gODF_ProjectionLabel';
            app.Label_Projection.Text = 'Projection';
            app.Label_ContourExtremum = uilabel(app.BaseGrid);
            app.Label_ContourExtremum.Tag = 'gCon_ExtremumLabel';
            app.Label_ContourExtremum.Enable = 'off';
            app.Label_ContourExtremum.Text = 'Extremum';
            app.Label_ContourLevel = uilabel(app.BaseGrid);
            app.Label_ContourLevel.Tag = 'gCon_LevelLabel';
            app.Label_ContourLevel.Enable = 'off';
            app.Label_ContourLevel.Text = {'Level'; ''};
            app.Label_PF_Pole = uilabel(app.BaseGrid);
            app.Label_PF_Pole.Tag = 'gPF_PoleLabel';
            app.Label_PF_Pole.Enable = 'off';
            app.Label_PF_Pole.Text = 'Pole';
            app.Button_RemovePoints = uibutton(app.BaseGrid, 'push');
            app.Button_RemovePoints.Tag = 'RemovePointsButton';
            app.Button_RemovePoints.Enable = 'off';
            app.Button_RemovePoints.Text = 'Remove Points';
            app.Panel = uipanel(app.MainWindow);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DRAGON

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainWindow)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MainWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end